classdef attendencesystem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        EnterIDBelowLabel  matlab.ui.control.Label
        TextArea           matlab.ui.control.TextArea
        EnterIDEditField   matlab.ui.control.EditField
        DisplayLabel       matlab.ui.control.Label
        HallDiningEntryManagementSystemLabel  matlab.ui.control.Label
        NewEntryButton     matlab.ui.control.Button
        TestButton         matlab.ui.control.Button
    end


    methods (Access = public)

        function dataentry(app)


            id=app.EnterIDEditField.Value;

            maindir=['data\' id];
            mkdir(maindir);
            iddir=['data\' id '\ID']; mkdir(iddir);
            namedir=['data\' id '\NAME']; mkdir(namedir);

            fs=44100;
            recobj=audiorecorder(fs,16,1);
            text={};
            p=1;

            idx=1;
            
            A=load('vq_books_train\ID.mat');
            ID=A.ID;

            A=load('vq_books_train\NAME.mat');
            NAME=A.NAME;
            

            text={};
            while p<=2
                pause(1);
                
                text{idx,1}=char('Speak your ID in 3s:');

                app.TextArea.Value=text;
                idx=idx+1;
                recordblocking(recobj,3);
                y=getaudiodata(recobj);
                g=project_mfcc(y); g=g';
                CB1=vqsplit(g,8);

                f=[id '_' num2str(p) '.wav'];
                DIR=[iddir '\' f];
                audiowrite(DIR,y,fs);

                pause(1);

                text{idx,1}=char('Enter Name in 3s:');
                app.TextArea.Value=text;

                idx=idx+1;

                recordblocking(recobj,3);
                y=getaudiodata(recobj);
                g=project_mfcc(y); g=g';
                CB2=vqsplit(g,8);

                f=[id '_' num2str(p) '.wav'];
                DIR=[namedir '\' f];
                audiowrite(DIR,y,fs);


                n=size(ID,1);
                ID{n+1,1}=CB1; ID{n+1,2}=id;

                n=size(NAME,1);
                NAME{n+1,1}=CB2; NAME{n+1,2}=id;


                p=p+1;
            end

            save('vq_books_train\ID.mat','ID');
            save('vq_books_train\NAME.mat','NAME');
            text={};
            text{1,1}='Data Entry Complete';
            text{2,1}='Thank You!!';
            app.TextArea.Value=text;

            app.EnterIDEditField.Value='';


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)

            text={};


            recobj=audiorecorder(44100,16,1);
            fs=44100;

            text{1,1}='Speak your ID';
            app.TextArea.Value=text;


            text{2,1}='start:';
            app.TextArea.Value=text;
            
            pause(0.5)

            recordblocking(recobj,3);

            x =getaudiodata(recobj);
            sound(x,fs); pause(3);
            g=project_mfcc(x); g=g';


            pause(1);

            text{3,1}='Speak your Name';
            app.TextArea.Value=text;


            text{4,1}='start:';
            app.TextArea.Value=text;
            pause(0.5)
            recordblocking(recobj,3);

            y =getaudiodata(recobj);
            sound(y,fs);
            h=project_mfcc(y); h=h';

            %%
            A=load('vq_books_train\ID.mat');
            ID=A.ID;
            A=load('vq_books_train\NAME.mat');
            NAME=A.NAME;

            %%
            dmin=inf;
            for ii=1:size(ID,1)
                d=my_dist(g,ID{ii,1});
                if d<dmin
                    dmin=d;
                    id1=ID{ii,2};
                end
            end

            dmin=inf;
            for ii=1:size(NAME,1)
                d=my_dist(h,NAME{ii,1});
                if d<dmin
                    dmin=d;
                    id2=NAME{ii,2};
                end
            end

            disp(id1); disp(id2);

            load('atsheet.mat');
            

            t=now;
            d=datestr(t);
            idx=size(attendsheet,1);
            attendsheet{idx+1,1}=id1;
            attendsheet{idx+1,2}=d;
            save('atsheet.mat','attendsheet');

            if strcmp(id1,id2)==1
                disp('access granted');
                text={};
                text{1,1}='Access Granted';
                text{2,1}=['From ID speech:' id1];
                text{3,1}=['From Name Speech:' id2];
                app.TextArea.Value=text;
            else
                disp('access denied');

                text={};
                text{1,1}='Access Denied';
                text{2,1}=['From ID Speech:' id1];
                text{3,1}=['From Name Speech:' id2];
                app.TextArea.Value=text;
            end

            filename='Attendencebook.xlsx';
    
            xlswrite(filename,attendsheet);


        end

        % Button pushed function: NewEntryButton
        function NewEntryButtonPushed(app, event)
            dataentry(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.NewEntryButton = uibutton(app.UIFigure, 'push');
            app.NewEntryButton.ButtonPushedFcn = createCallbackFcn(app, @NewEntryButtonPushed, true);
            app.NewEntryButton.Text = 'New Entry';
            app.HallDiningEntryManagementSystemLabel = uilabel(app.UIFigure);
            app.HallDiningEntryManagementSystemLabel.Text = 'Hall Dining  Entry Management System';
            app.DisplayLabel = uilabel(app.UIFigure);
            app.DisplayLabel.Text = 'Display';
            app.EnterIDEditField = uieditfield(app.UIFigure, 'text');
            app.TextArea = uitextarea(app.UIFigure);
            app.EnterIDBelowLabel = uilabel(app.UIFigure);
            app.EnterIDBelowLabel.Text = 'Enter ID Below';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = attendencesystem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end